classdef BalanceCalculator_Creator_v1_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LicenseCheckLabel             matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        BatchCreationLabel            matlab.ui.control.Label
        CopyPlotDataButton_2          matlab.ui.control.Button
        CopyPlotDataButton            matlab.ui.control.Button
        SaveCalculatorCheckBox        matlab.ui.control.CheckBox
        CalculatorNameEditField       matlab.ui.control.EditField
        CalculatorNameEditFieldLabel  matlab.ui.control.Label
        SaveBatchButton               matlab.ui.control.Button
        PasteSCButton                 matlab.ui.control.Button
        RunScriptButton               matlab.ui.control.Button
        IsSF1SplitCheckBox            matlab.ui.control.CheckBox
        Lamp                          matlab.ui.control.Lamp
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        BalanceCalculatorCreatorv13Label  matlab.ui.control.Label
        Plot2YDropDown                matlab.ui.control.DropDown
        Plot2YDropDownLabel           matlab.ui.control.Label
        Plot2XDropDown                matlab.ui.control.DropDown
        Plot2XDropDownLabel           matlab.ui.control.Label
        Plot1YDropDown                matlab.ui.control.DropDown
        Plot1YDropDownLabel           matlab.ui.control.Label
        Plot1XDropDown                matlab.ui.control.DropDown
        Plot1XDropDownLabel           matlab.ui.control.Label
        SummaryPathEditField          matlab.ui.control.EditField
        SummaryPathEditFieldLabel     matlab.ui.control.Label
        FMPathEditField               matlab.ui.control.EditField
        FMPathEditFieldLabel          matlab.ui.control.Label
        SummaryButton                 matlab.ui.control.Button
        FilteredMetricsButton         matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        lastfolder %stores last opened folder location
        resultsPath %path of results csv
        resultsFile %file name of results csv
        resultsFP %filepath of results csv
        summaryPath
        summaryFile
        summaryFP
        paramIdx
        paramData_coll
        outputData_coll
        

    end

    methods (Access = private)

        function showNotification(app, message, duration)
            % Create a small non-blocking UI figure
            notifFig = uifigure('Position', [app.ShockDOERankUIFigure.Position(1) + 500, ...
                app.ShockDOERankUIFigure.Position(2) + 350, 300, 100], ...
                'Name', 'Notification', 'Resize', 'off', 'MenuBar', 'none', ...
                'ToolBar', 'none', 'WindowStyle', 'normal');

            % Create a label inside the UI figure
            uilabel(notifFig, 'Text', message, 'Position', [20, 40, 260, 30], 'FontSize', 14, 'HorizontalAlignment', 'center');

            % Use a timer to close it automatically
            t = timer('StartDelay', duration, 'TimerFcn', @(~,~) close(notifFig));
            start(t);
        end

        function d = makeProgressBar(app, titleText)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', titleText, ...
                'Message', sprintf('%s...', titleText), ...
                'Indeterminate', 'off', ...
                'Value', 0, 'Cancelable','on');

        end


        function plotVarPlot(app, ui, xvar, yvar)
            idxX = contains(app.paramData_coll(1,:), xvar);
            idxY = contains(app.outputData_coll(1,:), yvar);
            currParamIdx = cell2mat(app.paramIdx(2,contains(app.paramIdx(1,:),xvar)));

            currInputs = app.paramData_coll(currParamIdx, idxX);
            if contains(xvar, 'Blade')
                for q = 1:numel(currInputs)
                    currInputs{q} = replace(currInputs{q}, 'P', '');
                end
            end
            currInputs = cell2mat(convertCellChars2Num(currInputs));
            currOutputs = cell2mat(convertCellChars2Num(app.outputData_coll(currParamIdx, idxY)));

            %plot(ui, currInputs, currOutputs, 'k-*');


            [p1, s1] = polyfit(currInputs, currOutputs, 1);
            [p3, s3] = polyfit(currInputs, currOutputs, 3);

            minRlinear = 0.9;
            minRquad = 0.7;

            if s1.rsquared > minRlinear || numel(currOutputs) <= 3
                plot(ui, currInputs, currOutputs, 'k-*');
                hold(ui, 'on');
                y1 = polyval(p1, currInputs);
                %plot(ui, currInputs, y1, 'g--')
                fplot(ui, @(x) x*p1(1) + p1(2), [min(currInputs) max(currInputs)], 'g--');
                hold(ui, 'off');
                legend(ui, 'DOE Data','Curve Fit')
                
                eqStr = sprintf('y = %.3fx + %.3f\nR^2 = %.4f', p1(1), p1(2), s1.rsquared);


                text(ui, ...
                    'Units', 'normalized', ...
                    'Position', [0.05, 0.9], ...  % Top-left corner
                    'String', eqStr, ...
                    'FontSize', 12, ...
                    'BackgroundColor', 'w', ...
                    'EdgeColor', 'k');

            else
                plot(ui, currInputs, currOutputs, 'k-*');
                hold(ui, 'on');
                y3 = polyval(p3, currInputs);
                %plot(ui, currInputs, y3, 'r--');
                fplot(ui, @(x) (x^3*(p3(1)) + x^2*(p3(2)) + p3(3)*x + p3(4)), [min(currInputs) max(currInputs)], 'b--');
                hold(ui, 'off');
                legend(ui, 'DOE Data','Curve Fit');

                eqStr = sprintf('y = %.3fx^3 + %.3fx^2 + %.3fx + %.3f\nR^2 = %.4f', p3(1), p3(2), p3(3), p3(4), s3.rsquared);


                text(ui, ...
                    'Units', 'normalized', ...
                    'Position', [0.05, 0.9], ...  % Top-left corner
                    'String', eqStr, ...
                    'FontSize', 12, ...
                    'BackgroundColor', 'w', ...
                    'EdgeColor', 'k');

            end

        end

        function [xdata, ydata] = copyPlotData(app, xvar, yvar)
            idxX = contains(app.paramData_coll(1,:), xvar);
            idxY = contains(app.outputData_coll(1,:), yvar);
            currParamIdx = cell2mat(app.paramIdx(2,contains(app.paramIdx(1,:),xvar)));

            currInputs = app.paramData_coll(currParamIdx, idxX);
            if contains(xvar, 'Blade')
                for q = 1:numel(currInputs)
                    currInputs{q} = replace(currInputs{q}, 'P', '');
                end
            end
            xdata = cell2mat(convertCellChars2Num(currInputs));
            ydata = cell2mat(convertCellChars2Num(app.outputData_coll(currParamIdx, idxY)));


        end

        function checkAppLicense(app)
            % --- Local fallback expiration date ---


            expirationDate = getExpirationDate_xor;

            % --- Try online validation first ---
            try
                obf = 'kwwsv=22udz1jlwkxexvhufrqwhqw1frp2hw53s2vsp2uhiv2khdgv2pdlq2olfhqvhFkhfn1w{w';
                status = webread(getLicenseURL(obf));

                if ~strcmpi(strtrim(status), 'valid')
                    % uialert(uifigure, ...
                    %     'This app license is no longer valid. Please contact the developer.', ...
                    %     'License Error');
                    error('App license expired');
                    validity = false;
                else
                    validity = true;
                end


            catch
                % --- If online check fails, fallback to local expiration date ---
                if datetime("today") > expirationDate
                    showAndQuit(app, 'This app license has expired. Please contact the developer.');
                    error('App license expired');
                    validity = false;
                else
                    validity = true;
                end
            end

            if validity == true
                app.Lamp_2.Visible = "on";
            end


        end


        function showAndQuit(app, msg)
            % Show alert in the app UI (use UIFigure so it appears inside the app)
            try
                uialert(app.UIFigure, msg, 'License Error');
            catch
                % fallback if UIFigure not available for some reason
                disp(['License Error: ', msg]);
            end

            % Give the user a moment to see the alert
            pause(2);

            % Close the app UI
            try
                delete(app);   % removes the App Designer app object & closes UI
            catch
                % ignore if delete fails
            end

            % If deployed, also exit the MATLAB Runtime so process ends.
            % When running inside MATLAB (development), don't call exit().
            if isdeployed
                pause(1);   % short pause to ensure UI closes
                exit;         % terminate the runtime process
            else
                % In development: raise an error to stop the rest of startup code
                error('App license invalid or expired - stopping execution (development mode).');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            checkAppLicense(app);
        end

        % Button pushed function: FilteredMetricsButton
        function FilteredMetricsButtonPushed(app, event)
            if isempty(app.lastfolder)
                startPath = pwd;
            else
                startPath = app.lastfolder;
            end

            [app.resultsFile, app.resultsPath] = uigetfile('*.csv', 'Select Filtered Results', startPath);

            if app.resultsFile ~= 0
                try
                    app.lastfolder = app.resultsPath;
                    app.resultsFP = fullfile(app.resultsPath, app.resultsFile);
                    app.FMPathEditField.Value = app.resultsFile;
                catch ME
                    uialert(app.UIFigure, "Error Loading Results","Failed to Load","Icon","error");
                    focus(app.UIFigure);
                end
            end


        end

        % Button pushed function: SummaryButton
        function SummaryButtonPushed(app, event)
            if isempty(app.lastfolder)
                startPath = pwd;
            else
                startPath = app.lastfolder;
            end

            [app.summaryFile, app.summaryPath] = uigetfile('*.csv', 'Select Summary', startPath);

            if app.summaryFile ~= 0
                try
                    app.lastfolder = app.summaryPath;
                    app.summaryFP = fullfile(app.summaryPath, app.summaryFile);
                    app.SummaryPathEditField.Value = app.summaryFile;
                catch ME
                    uialert(app.UIFigure, "Error Loading Summary","Failed to Load","Icon","error");
                    focus(app.UIFigure);
                end
            end

        end

        % Button pushed function: RunScriptButton
        function RunScriptButtonPushed(app, event)


            if app.SaveCalculatorCheckBox.Value == true
                justFN = split(app.resultsFP, '_FilteredResults');
                justFN = justFN{1};

                if ~isempty(app.CalculatorNameEditField.Value)
                    a = split(justFN, '\');
                    b = join(a(1:end-1),'\');
                    savePath = strcat(b,'\',app.CalculatorNameEditField.Value,'.xlsm');
                    savePath = savePath{1};
                else
                    savePath = strcat(justFN, '_BC','.xlsm');

                end

                if isfile(savePath)
                    selection = uiconfirm(app.UIFigure, "File name exists, Overwrite?",...
                        "Duplicate File Name",'Icon','warning');
                    if strcmp(selection, 'Cancel')
                        return
                    end
                end
            end


            d = app.makeProgressBar("Running...");


            if d.CancelRequested == 1
                close(d)
                return
            end

            resultsRaw = readmatrix(app.resultsFP, "Range", "A1", 'OutputType','char');
            summaryRaw = readmatrix(app.summaryFP, "Range", "A1", 'OutputType', 'char');

            load("paramHeaders.mat")
            load("batchIndexes_v1.mat")

            d.Value = .25;
            d.Message = "Data read, getting indexes...";


            idxFailed = find(contains(summaryRaw(:,2),'Failed')); %should be a +1 offset from batchIndexes. Batch indexes should be line up with the actual sim ID
            simIDFailed = cell2mat(convertCellChars2Num((summaryRaw(idxFailed,1))));

            [~, nc] = size(batchIndexes);

            % Get indexes of failed sims and match with batch indexes, use
            % this to recount indexes of completed sims
            for k = 1:nc
                if any(ismember(simIDFailed, batchIndexes{2,k}))
                    [~, idx] = ismember(batchIndexes{2,k},simIDFailed);
                    mask = idx == 0;
                    keepIdx = batchIndexes{2,k}(mask);
                    batchIndexes{2,k} = keepIdx;

                end
            end
            idxHooked = contains(batchIndexes(1,:), 'Cross Weight % Race Hooked');
            batchIndexes = batchIndexes(:,~idxHooked);

            % Get sim outputs, and combine the split front stretch into one
            % average or max

            if d.CancelRequested == 1
                close(d)
                return
            end


            [paramData, outputs, lapTime, simIDs] = getDataOfInterest(resultsRaw,m_paramHeaders, app.IsSF1SplitCheckBox.Value);

            % find where laptime equals 0, can also tell where a Sim has
            % failed or not run properly
            idxIsNum = cellfun(@isnumeric, lapTime);
            lapTime_num = convertCellChars2Num(lapTime);
            idxEmpty = cellfun(@isempty, lapTime_num);
            lapTime_num(idxEmpty) = num2cell(NaN);
            lapTime_num = cell2mat(lapTime_num);
            idx0LapTime = (lapTime_num == 0);
            rowsWithTrue = find(any(idx0LapTime, 2));

            if d.CancelRequested == 1
                close(d)
                return
            end

            % Extract sector and output names for later
            outputNames = outputs(1,:); % output names + segments, first row of outputs
            idxUnderScore = strfind(outputNames(1,:), '_'); % Assuming output names occur before the first underscore

            for k = 1:length(idxUnderScore)
                sectorNames{k} = outputNames{k}(idxUnderScore{k}(1)+1:end); % extracted segment name
                outputNames{k} = outputNames{k}(1:idxUnderScore{k}(1)-1); % extracted output name

            end

            outputNames = unique(outputNames);
            sectorNames = unique(sectorNames);

            %idxUnhooked = find(contains(paramData(1,:), 'Unhooked'));
            idxToeAngle = find(contains(paramData(1,:), 'Toe Angle'));
            idxfARB = find(contains(paramData(1,:), 'ARB Front'));
            idxrARB = find(contains(paramData(1,:), 'ARB Rear'));
            idxUD0 = find(contains(paramData(1,:),'UD0'));

            paramData(:, [idxToeAngle, idxfARB, idxrARB, idxUD0]) = [];
            % %% Find Parameter Indexes, correct them based on indexes
            %
            baselineParam = paramData(2,:);
            [~, nc] = size(batchIndexes);
            n = 3;
            paramDeltasIdx = batchIndexes(1,:);
            for i = 1:nc
                paramDeltasIdx{2,i} = n:n+numel(batchIndexes{2,i})-1;
                n = n+numel(batchIndexes{2,i});
            end

            d.Value = 0.5;
            d.Message = "Curve Fitting...";

            % Get outputs of interest

            outputsOI = getCalcData(outputs, lapTime);

            idxEmpty = find(cellfun(@isempty, outputsOI));

            if d.CancelRequested == 1
                close(d)
                return
            end

            % Curve fit
            minRlinear = 0.9;
            minRquad = 0.7;

            % warning('off', 'MATLAB:polyfit:PolyNotUnique');

            q = 1;

            linFit(2:size(paramData,2)+1,1) = paramData(1,:);
            linFit(1,2:size(outputsOI,2)+1) = outputsOI(1,:);

            polyFit = linFit;
            polyFit{1,1} = "a";
            polyFit{size(paramData,2) + 2, 1} = "b";
            boffset = size(paramData,2) + 1;
            polyFit{boffset + size(paramData,2) + 2,1} = "c";
            coffset = boffset + size(paramData,2) + 1;

            polyFit(boffset + 2:boffset+2+size(paramData,2)-1,1) = paramData(1,:);
            polyFit(coffset + 2: coffset+2+size(paramData,2)-1,1) = paramData(1,:);

            polyFit{coffset + size(paramData,2) + 2,1} = "d";
            doffset = coffset + size(paramData,2) + 3;
            polyFit(doffset:doffset+size(paramData,2)-1,1) = paramData(1,:);

            polyFit{doffset + size(paramData,2),1} = "Baseline Data";
            baseOffset = doffset + size(paramData,2) + 1;


            linFit{end+1,1} = "Baseline Setup";
            [nr, ~] = size(linFit);
            for k = 1:size(paramData,2)
                currB = paramData{2,k};
                currB = replace(currB, 'P', '');
                currB = str2num(currB);
                linFit{nr,k+1} = paramData{1,k};
                linFit{nr+1,k+1} = currB;
            end

            r = 2; c = 2;
            for i = 1:size(paramData,2)
                currInputIdx = find(contains(paramDeltasIdx(1,:), paramData{1,i}));
                currInputDeltaIdx = paramDeltasIdx{2,currInputIdx};

                currInputs = paramData(currInputDeltaIdx, i);

                if contains(paramData(1,i), 'Blade')
                    for q = 1:numel(currInputs)
                        currInputs{q} = replace(currInputs{q}, 'P', '');
                    end
                end

                currInputs = convertCellChars2Num(currInputs);
                rawInputs = currInputs;

                for o = 1:size(outputsOI,2)

                    currOutputs = outputsOI(currInputDeltaIdx, o);

                    currOutputs = convertCellChars2Num(currOutputs);
                    idxIsEmpty = find(cellfun(@isempty, currOutputs));
                    currOutputs(idxIsEmpty) = {0};

                    idxIs0LapTime = ismember(currInputDeltaIdx, rowsWithTrue);
                    if any(idxIs0LapTime)
                        currInputs = currInputs(~idxIs0LapTime);
                        currOutputs = currOutputs(~idxIs0LapTime);
                    end

                    xraw = cell2mat(currInputs);
                    yraw = cell2mat(currOutputs);

                    [y, io] = rmoutliers(yraw, 'movmedian',2);
                    x = xraw;
                    x(io) = [];

                    if nnz(io) ~= 0
                        removedOutliers{q,1} = paramDeltasIdx{1,currInputIdx};
                        removedOutliers{q,2} = outputsOI{1,o};
                        q = q + 1;
                    end

                    if numel(x) <= 3
                        fprintf('Few points:\nx: %s  \ny: %s\n', paramData{1,i}, outputsOI{1,o})
                        fprintf('Num Outliers = %d\n',nnz(io))
                        fprintf('Num Points Remaining = %d\n\n', numel(xraw) - nnz(io))
                        fprintf('----------------------------------------------')
                    end
                    [pl, sl] = polyfit(x, y, 1);
                    [pq, sq] = polyfit(x, y, 3);

                    polyFit{baseOffset, c} = cell2mat(convertCellChars2Num(outputsOI(2,o)));

                    if d.CancelRequested == 1
                        close(d)
                        return
                    end


                    if sl.rsquared > minRlinear || numel(y) <= 3
                        linFit{r,c} = pl(1);
                        polyFit{r,c} = 12345;
                        polyFit{r + boffset, c} = 12345;
                        polyFit{r + coffset, c} = 12345;
                        polyFit{r + doffset - 2, c} = 12345;

                    else
                        linFit{r,c} = 12345;
                        polyFit{r,c} = pq(1);
                        polyFit{r + boffset, c} = pq(2);
                        polyFit{r + coffset, c} = pq(3);
                        polyFit{r + doffset - 2, c} = pq(4);
                    end


                    c = c + 1;

                    currInputs = rawInputs;

                    if numel(currOutputs) < 3
                        5;
                    end
                end

                c = 2;
                r = r + 1;
            end

            if d.CancelRequested == 1
                close(d)
                return
            end

            d.Value = 0.75;
            d.Message = "Curve fit successful, extracting plot data...";


            % Bumpstop Data
            bumpstopDataidx = contains(outputsOI(1,:),'Bumpstop');
            bumpstopData = outputsOI(:,bumpstopDataidx);

            [nr, ~] = size(bumpstopData);
            divider = repmat({'Pause'},nr,1);
            ender = repmat({'End'},nr,1);

            stopViewer = [paramData, divider, bumpstopData, ender];

            stopViewerIdx = paramDeltasIdx(1,:);
            [~, nc] = size(stopViewerIdx);
            for c = 1:nc
                l = numel(paramDeltasIdx{2,c});
                for k = 1:l
                    stopViewerIdx{k+1,c} = paramDeltasIdx{2,c}(k);
                end
            end

            b_paramHeaders = paramData(1,:)';
            b_bumpstopHeaders = bumpstopData(1,:)';

            if d.CancelRequested == 1
                close(d)
                return
            end


            d.Value = 0.9;
            d.Message = "Exporting Data...";

            if app.SaveCalculatorCheckBox.Value == true

                % Export Data
                [filename, pathname] = uigetfile('*.xlsm','Select Template File');

                templatePath = fullfile(pathname, filename);
                justFN = split(app.resultsFP, '_FilteredResults');
                justFN = justFN{1};

                if ~isempty(app.CalculatorNameEditField.Value)
                    a = split(justFN, '\');
                    b = join(a(1:end-1),'\');
                    savePath = strcat(b,'\',app.CalculatorNameEditField.Value,'.xlsm');
                    savePath = savePath{1};
                else
                    savePath = strcat(justFN, '_BC','.xlsm');

                end


                copyfile(templatePath, savePath);

                if d.CancelRequested == 1
                    close(d)
                    return
                end

                writecell(linFit(1,2:end), savePath, 'Sheet', '1stD', 'Range', 'B1'); %write linear fit headers
                writecell(linFit(2:end,:), savePath, 'Sheet', '1stD', 'Range', 'A3') %write linfit data

                writecell(polyFit(1,2:end), savePath, 'Sheet', '3rdD', 'Range', 'B1') %write poly headers
                writecell(polyFit(2:end,:), savePath, 'Sheet', '3rdD', 'Range', 'A3') %write poly data

                if d.CancelRequested == 1
                    close(d)
                    return
                end

                writecell(stopViewer, savePath, 'Sheet', 'Bumpstop Viewer', 'Range', 'BA1');
                writecell(b_paramHeaders, savePath, 'Sheet', 'Bumpstop Viewer', 'Range', 'AX10');
                writecell(b_bumpstopHeaders, savePath, 'Sheet', 'Bumpstop Viewer', 'Range', 'AY10');

                writecell(stopViewerIdx, savePath, 'Sheet', 'Stop Viewer Data', 'Range', 'B1');
                %disp('Done exporting')

                d.Message = "Complete";
                d.Value = 1;
                close(d)

            end
            close(d)

            if app.SaveCalculatorCheckBox.Value == true
                uialert(app.UIFigure,"Script Complete, Calculator Ready",'Success','Icon','success');
            else
                uialert(app.UIFigure,"Script Complete, Plots Ready",'Success','Icon','success');
            end
            focus(app.UIFigure);


            % Add variation plot stuff %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            paramList = paramDeltasIdx(1,:);
            outputList = outputsOI(1,:);

            app.paramIdx = paramDeltasIdx;
            app.paramData_coll = paramData;
            app.outputData_coll = outputsOI;

            app.Plot1XDropDown.Items = paramList;
            app.Plot1YDropDown.Items = outputList;

            app.Plot2XDropDown.Items = paramList;
            app.Plot2YDropDown.Items = outputList;

            app.Plot1XDropDown.Value = paramList{1};
            app.Plot1YDropDown.Value = outputList{1};
            app.Plot2XDropDown.Value = paramList{2};
            app.Plot2YDropDown.Value = outputList{2};

            plotVarPlot(app, app.UIAxes, app.Plot1XDropDown.Value,app.Plot1YDropDown.Value)
            plotVarPlot(app, app.UIAxes2, app.Plot2XDropDown.Value,app.Plot2YDropDown.Value)

            app.CopyPlotDataButton.Enable = "on";
            app.CopyPlotDataButton_2.Enable = "on";
        end

        % Button pushed function: PasteSCButton
        function PasteSCButtonPushed(app, event)
            try
                pastedText = clipboard('paste');

                if isempty(pastedText)
                    uialert(app.UIFigure,"No Data from Clipboard","Error",'Icon','error');
                    focus(app.UIFigure)
                end

                % split up into rows
                rows = splitlines(strtrim(pastedText));

                %Remove empty rows
                rows(cellfun(@isempty, rows)) = [];

                if isempty(rows)
                    uialert(app.UIFigure,"No Data from Clipboard","Error",'Icon','error');
                    focus(app.UIFigure)
                end
                % Detect the delimiter (tab '\t', comma ',', or space)
                sampleRow = rows{1};  % Use the first row as an example
                if contains(sampleRow, char(9))  % Tab character
                    delimiter = char(9);
                elseif contains(sampleRow, ',')  % Comma
                    delimiter = ',';
                elseif contains(sampleRow, ' ')  % Space
                    delimiter = ' ';
                else
                    error("Unknown delimiter. Please check the copied data format.");
                end

                % Split each row into columns using the detected delimiter
                dataArray = cellfun(@(x) split(x, delimiter)', rows, 'UniformOutput', false);  % Transpose to align correctly
                dataArray = dataArray(1:end-1,:);

                % Convert nested cell array into a proper 4x4 cell array
                input = cat(1, dataArray{:});

                input = cell2table(input, "VariableNames", {'Propety', 'Value'});
                app.UITable.Data = input;
                app.UITable.Visible = "on";
                app.UITable.ColumnName = {'Propety', 'Value'};

                app.Lamp.Visible = "on";

            catch ME
                uialert(app.UIFigure,"Clipboard Error","Error",'Icon','error');
                focus(app.UIFigure)
            end
        end

        % Button pushed function: SaveBatchButton
        function SaveBatchButtonPushed(app, event)
            baselineSetup = table2cell(app.UITable.Data)';
            load("batchHeaders_template.mat");
            batchRangeIdx = batchheaders;

            for k = 1:length(batchRangeIdx)
                currParam = batchRangeIdx{1,k};
                if contains(currParam, 'ARB Arm Blade') % Blade Positions
                    batchRangeIdx{2,k} = [{'P1'},{'P2'},{'P3'},{'P4'},{'P5'}];
                elseif contains(currParam, 'Rollout Delta RR') % Rollout Delta (RR)
                    batchRangeIdx{2,k} = num2cell(linspace(-2,2,9));
                elseif contains(currParam, 'ARB Rounds') % ARB Rounds
                    batchRangeIdx{2,k} = num2cell(linspace(-4,4,8));
                elseif contains(currParam, 'Fuel Mass') % Addl' Fuel Mass
                    batchRangeIdx{2,k} = num2cell(linspace(-15,15,8));
                elseif contains(currParam, 'Spring Rounds') % Spring Rounds
                    batchRangeIdx{2,k} = num2cell(linspace(-4,4,10));
                elseif contains(currParam, 'Air Pressure') %Air Pressure
                    batchRangeIdx{2,k} = num2cell(linspace(-2,2,5));
                elseif contains(currParam, 'Air Temperature') % Air Temp
                    batchRangeIdx{2,k} = num2cell(linspace(-15,15,6));
                elseif contains(currParam, 'Relative Humidity') % Humidity
                    batchRangeIdx{2,k} = num2cell(linspace(-10,10,5));
                elseif contains(currParam, 'Camber') % Camber
                    batchRangeIdx{2,k} = num2cell(linspace(-1.5,1.5,10));
                elseif contains(currParam, 'Spring Rate')
                    batchRangeIdx{2,k} = num2cell(linspace(-400,400,10));
                elseif contains(currParam, 'Toe Inches') % Toe"
                    batchRangeIdx{2,k} = num2cell(linspace(-.1,.1,10));
                elseif contains(currParam, 'Diff') % Diff Preload Pressure
                    batchRangeIdx{2,k} = num2cell(linspace(-250,250,5));
                elseif contains(currParam, 'Cross Weight') % CW - Hooked and UH
                    batchRangeIdx{2,k} = num2cell(linspace(-3.5,3.5,12));
                elseif contains(currParam, 'Nose Weight') %NW
                    batchRangeIdx{2,k} = num2cell(linspace(-2,2,12));
                elseif contains(currParam, 'Frame Height')
                    batchRangeIdx{2,k} = num2cell(linspace(-.1,.1,10));
                elseif contains(currParam, 'Tire Cold Pressure')
                    batchRangeIdx{2,k} = num2cell(linspace(-4,8,12));
                elseif contains(currParam, 'Right Side')
                    batchRangeIdx{2,k} = num2cell(linspace(-300,300,8));
                elseif contains(currParam, 'Track Mu')
                    batchRangeIdx{2,k} = num2cell(linspace(-0.2,.5,15));
                else
                    uialert(app.UIFigure,'Error with Pulling Setup Compare Data','Error',Icon='error');
                    %fprintf('Missed one\n');
                end
            end

            [~, nc] = size(batchRangeIdx);
            batchInputs = batchRangeIdx(1,:);
            for c = 1:nc
                currParam = batchRangeIdx{1,c}; % based off sample batch
                currRange = batchRangeIdx{2,c};
                if contains(currParam, 'Atmospheric Conditions')
                    idxdash = strfind(currParam, ' - ');
                    fixedParam = currParam(idxdash+3:end);
                    currParam = fixedParam;
                elseif contains(currParam, 'Toe Inches')
                    corner = currParam(end-1:end);
                    currParam = append('Toe Length ', corner, ' Tech');
                elseif contains(currParam, 'Speed Profile Lap Sim Track Mu')
                    currParam = 'DLS Track Mu';
                end

                idxParamBaseline = find(contains(baselineSetup(1,:), currParam), 1);
                currBaseline = baselineSetup{2,idxParamBaseline}; % from setup compare
                if contains(currParam, 'Blade Position')
                    batchInputs(2:1+numel(currRange),c) = currRange';
                elseif contains(currParam, 'Rollout Delta')
                    batchInputs(2:1+numel(currRange),c) = currRange';
                else

                    batchInputs(2:1+numel(currRange),c) = num2cell(str2num(currBaseline) + cell2mat(currRange))';
                end
                baseline4Batch{2,c} = currBaseline;
                baseline4Batch{1,c} = currParam;


            end

            totalElements = 0;

            [nr, nc] = size(batchInputs(1:end,:));
            for c = 1:nc
                for r = 2:nr
                    t = batchInputs{r,c};
                    if ~isempty(batchInputs{r,c})
                        totalElements = totalElements + 1;
                    end
                end
            end

            idxUHCW = contains(batchInputs(1,:),'Cross Weight % Race Unhooked');
            idxHCW = contains(batchInputs(1,:),'Cross Weight % Race Hooked');
            inputs_UHCW = batchInputs(:,idxUHCW);
            idxEmpty = cellfun(@isempty,inputs_UHCW);
            numCWRuns = numel(inputs_UHCW) - sum(idxEmpty) - 1;
            numRuns = totalElements - numCWRuns;
            fprintf('\nNum Generated Sim Runs (CW and UH combined): %d\n',numRuns);


            outputBatch = batchInputs(1,:);
            [~, nc] = size(batchInputs);
            currRow = 3;
            for c = 1:nc

                currCol = batchInputs(:,c);
                idxNonEmpty = ~cellfun(@isempty, currCol);
                currInputs = currCol(idxNonEmpty);
                outputBatch{1,c} = currInputs{1};
                outputBatch{2,c} = baseline4Batch{2,c};

                if c == find(idxUHCW)
                    outputBatch(currRow:currRow + numel(currInputs)-2,c) = currInputs(2:end);
                    outputBatch(currRow:currRow + numel(currInputs)-2,c+1) = currInputs(2:end);
                    currRow = currRow + numel(currInputs) - 1;
                elseif c == find(idxHCW)
                    continue;
                else

                    outputBatch(currRow:currRow + numel(currInputs)-2,c) = currInputs(2:end);

                    currRow = currRow + numel(currInputs) - 1;
                end
            end

            [~, nCols] = size(outputBatch);

            for col = 1:nCols
                % Find empty cells in the current column
                emptyMask = cellfun(@isempty, outputBatch(:, col));

                % Replace empty cells with the value from row 2 of the same column
                outputBatch(emptyMask, col) = repmat(outputBatch(2, col), sum(emptyMask), 1);
            end

            % Don't need baseline row (row 2 in outputBatch), getting rid of it here:
            outputBatch(2,:) = [];

            [filename, pathname] = uiputfile('*.csv','Save as');
            if ischar(filename)
                fullpath = fullfile(pathname, filename);
                try
                    writetable(cell2table(outputBatch),fullpath, 'WriteVariableNames',false);
                    uialert(app.UIFigure, 'Batch Save Successful', 'Success',Icon='success');
                catch ME
                    uialert(app.UIFigure, 'Save Error', 'Error')
                end
            end


        end

        % Value changed function: Plot1XDropDown
        function Plot1XDropDownValueChanged(app, event)
            plotVarPlot(app, app.UIAxes, app.Plot1XDropDown.Value, app.Plot1YDropDown.Value);

        end

        % Value changed function: Plot1YDropDown
        function Plot1YDropDownValueChanged(app, event)
            plotVarPlot(app, app.UIAxes, app.Plot1XDropDown.Value, app.Plot1YDropDown.Value);

        end

        % Value changed function: Plot2XDropDown
        function Plot2XDropDownValueChanged(app, event)
            plotVarPlot(app, app.UIAxes2, app.Plot2XDropDown.Value, app.Plot2YDropDown.Value);

        end

        % Value changed function: Plot2YDropDown
        function Plot2YDropDownValueChanged(app, event)
            plotVarPlot(app, app.UIAxes2, app.Plot2XDropDown.Value, app.Plot2YDropDown.Value);
        end

        % Value changed function: SaveCalculatorCheckBox
        function SaveCalculatorCheckBoxValueChanged(app, event)
            value = app.SaveCalculatorCheckBox.Value;
            if value == false
                app.CalculatorNameEditField.Enable = "off";
            else
                app.CalculatorNameEditField.Enable = "on";
            end

        end

        % Button pushed function: CopyPlotDataButton
        function CopyPlotDataButtonPushed(app, event)
            xLabel = app.Plot1XDropDown.Value;
            yLabel = app.Plot1YDropDown.Value;

            [xdata, ydata] = copyPlotData(app, xLabel, yLabel);

            % Convert to strings
            xdataStr = [xLabel; string(xdata(:))];
            ydataStr = [yLabel; string(ydata(:))];

            % Compose tab-delimited rows
            rows = compose('%s\t%s', xdataStr, ydataStr);

            % Join rows with newline characters
            datastr = strjoin(rows, newline);

            % Copy to clipboard
            clipboard("copy", datastr);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Variation Plot 1')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Variation Plot 2')
            app.FilteredMetricsButton = uibutton(app.GridLayout, 'push');
            app.FilteredMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredMetricsButtonPushed, true);
            app.FilteredMetricsButton.Tooltip = {'Select the filtered metrics csv '};
            app.FilteredMetricsButton.Text = 'Filtered Metrics';
            app.SummaryButton = uibutton(app.GridLayout, 'push');
            app.SummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SummaryButtonPushed, true);
            app.SummaryButton.Tooltip = {'Select the summary csv'};
            app.SummaryButton.Text = 'Summary';
            app.FMPathEditFieldLabel = uilabel(app.GridLayout);
            app.FMPathEditFieldLabel.Enable = 'off';
            app.FMPathEditFieldLabel.Text = 'F.M. Path';
            app.FMPathEditField = uieditfield(app.GridLayout, 'text');
            app.FMPathEditField.Editable = 'off';
            app.FMPathEditField.Enable = 'off';
            app.SummaryPathEditFieldLabel = uilabel(app.GridLayout);
            app.SummaryPathEditFieldLabel.Enable = 'off';
            app.SummaryPathEditFieldLabel.Text = 'Summary Path';
            app.SummaryPathEditField = uieditfield(app.GridLayout, 'text');
            app.SummaryPathEditField.Editable = 'off';
            app.SummaryPathEditField.Enable = 'off';
            app.Plot1XDropDownLabel = uilabel(app.GridLayout);
            app.Plot1XDropDownLabel.Text = 'Plot 1 X';
            app.Plot1XDropDown = uidropdown(app.GridLayout);
            app.Plot1XDropDown.Items = {};
            app.Plot1XDropDown.Editable = 'on';
            app.Plot1XDropDown.ValueChangedFcn = createCallbackFcn(app, @Plot1XDropDownValueChanged, true);
            app.Plot1XDropDown.Value = {};
            app.Plot1YDropDownLabel = uilabel(app.GridLayout);
            app.Plot1YDropDownLabel.Text = 'Plot 1 Y';
            app.Plot1YDropDown = uidropdown(app.GridLayout);
            app.Plot1YDropDown.Items = {};
            app.Plot1YDropDown.Editable = 'on';
            app.Plot1YDropDown.ValueChangedFcn = createCallbackFcn(app, @Plot1YDropDownValueChanged, true);
            app.Plot1YDropDown.Value = {};
            app.Plot2XDropDownLabel = uilabel(app.GridLayout);
            app.Plot2XDropDownLabel.Text = 'Plot 2 X';
            app.Plot2XDropDown = uidropdown(app.GridLayout);
            app.Plot2XDropDown.Items = {};
            app.Plot2XDropDown.Editable = 'on';
            app.Plot2XDropDown.ValueChangedFcn = createCallbackFcn(app, @Plot2XDropDownValueChanged, true);
            app.Plot2XDropDown.Value = {};
            app.Plot2YDropDownLabel = uilabel(app.GridLayout);
            app.Plot2YDropDownLabel.Text = 'Plot 2 Y';
            app.Plot2YDropDown = uidropdown(app.GridLayout);
            app.Plot2YDropDown.Items = {};
            app.Plot2YDropDown.Editable = 'on';
            app.Plot2YDropDown.ValueChangedFcn = createCallbackFcn(app, @Plot2YDropDownValueChanged, true);
            app.Plot2YDropDown.Value = {};
            app.BalanceCalculatorCreatorv13Label = uilabel(app.GridLayout);
            app.BalanceCalculatorCreatorv13Label.Text = 'Balance Calculator Creator v1.3';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Spire_Horizontal+Badge.png');
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.GridLayout);
            app.Lamp.Visible = 'off';
            app.IsSF1SplitCheckBox = uicheckbox(app.GridLayout);
            app.IsSF1SplitCheckBox.Tooltip = {'If SF-1 segment is split (has to be if the whole straight is included)'};
            app.IsSF1SplitCheckBox.Text = 'Is SF-1 Split?';
            app.IsSF1SplitCheckBox.Value = true;
            app.RunScriptButton = uibutton(app.GridLayout, 'push');
            app.RunScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptButtonPushed, true);
            app.RunScriptButton.Text = 'Run Script';
            app.PasteSCButton = uibutton(app.GridLayout, 'push');
            app.PasteSCButton.ButtonPushedFcn = createCallbackFcn(app, @PasteSCButtonPushed, true);
            app.PasteSCButton.Tooltip = {'Paste Setup Compare from clipboard, must assemble first'};
            app.PasteSCButton.Text = 'Paste S.C.';
            app.SaveBatchButton = uibutton(app.GridLayout, 'push');
            app.SaveBatchButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBatchButtonPushed, true);
            app.SaveBatchButton.Tooltip = {''};
            app.SaveBatchButton.Text = 'Save Batch';
            app.CalculatorNameEditFieldLabel = uilabel(app.GridLayout);
            app.CalculatorNameEditFieldLabel.Text = 'Calculator Name:';
            app.CalculatorNameEditField = uieditfield(app.GridLayout, 'text');
            app.CalculatorNameEditField.Tooltip = {'If left blank, file will be the DOE name + _BC'};
            app.SaveCalculatorCheckBox = uicheckbox(app.GridLayout);
            app.SaveCalculatorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCalculatorCheckBoxValueChanged, true);
            app.SaveCalculatorCheckBox.Tooltip = {'Select this before running script to save'};
            app.SaveCalculatorCheckBox.Text = 'Save Calculator?';
            app.SaveCalculatorCheckBox.Value = true;
            app.CopyPlotDataButton = uibutton(app.GridLayout, 'push');
            app.CopyPlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPlotDataButtonPushed, true);
            app.CopyPlotDataButton.Enable = 'off';
            app.CopyPlotDataButton.Tooltip = {'Copy x and y data to clipboard'};
            app.CopyPlotDataButton.Text = 'Copy Plot Data';
            app.CopyPlotDataButton_2 = uibutton(app.GridLayout, 'push');
            app.CopyPlotDataButton_2.Enable = 'off';
            app.CopyPlotDataButton_2.Tooltip = {'Copy x and y data to clipboard'};
            app.CopyPlotDataButton_2.Text = 'Copy Plot Data';
            app.BatchCreationLabel = uilabel(app.GridLayout);
            app.BatchCreationLabel.Text = 'Batch Creation';
            app.Lamp_2 = uilamp(app.GridLayout);
            app.Lamp_2.Visible = 'off';
            app.LicenseCheckLabel = uilabel(app.GridLayout);
            app.LicenseCheckLabel.Text = 'License Check';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BalanceCalculator_Creator_v1_3

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end